classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlayButton                     matlab.ui.control.Button
        YourEarningsTextArea           matlab.ui.control.TextArea
        YourEarningsTextAreaLabel      matlab.ui.control.Label
        error_message_window           matlab.ui.control.TextArea
        BettingAmountEditField         matlab.ui.control.NumericEditField
        BettingAmountEditFieldLabel    matlab.ui.control.Label
        MinimumBettingAmountEditField  matlab.ui.control.NumericEditField
        MinimumBettingAmountEditFieldLabel  matlab.ui.control.Label
        Image7                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        DealersHandLabel               matlab.ui.control.Label
        YourHandLabel                  matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        SplitButton                    matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        card1                          matlab.ui.control.Image
        StayButton                     matlab.ui.control.Button
        HitButton                      matlab.ui.control.Button
        WelcometoOnlineBlackjackLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        deck_of_cards = [1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1];
        %current_card = [];
        user_count = 0;
        filename = 'C:\Users\prami\OneDrive\Desktop\CLPS_0950\CLPS_0950_project' + strcat(value) + strcat(suit) + strcat('.png');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MinimumBettingAmountEditField
        function MinimumBettingAmountEditFieldValueChanged2(app, event)

            minimum_bet = app.MinimumBettingAmountEditField.Value;
            placed_bet = app.BettingAmountEditField.Value;
            if placed_bet < 0
                app.error_message_window.Value = 'Please enter a valid betting amount before hitting play.  ';
            elseif placed_bet < minimum_bet
                app.error_message_window.Value = 'Please enter a value over the minimum betting amount before hitting play.';
            else
                app.error_message_window.Value = '';
            end
        end

        % Value changed function: BettingAmountEditField
        function BettingAmountEditFieldValueChanged(app, event)
            placed_bet = app.BettingAmountEditField.Value;
            minimum_bet = app.MinimumBettingAmountEditField.Value;
            if placed_bet < 0
                app.error_message_window.Value = 'Please enter a valid betting amount before hitting play.  ';
            elseif placed_bet < minimum_bet
                app.error_message_window.Value = 'Please enter a value over the minimum betting amount before hitting play.';
            else
                app.error_message_window.Value = '';
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

            [value, suit] = size(app.deck_of_cards); 
            card_in_deck = 0;
            while card_in_deck == 0
                row_index = randi(1,value);
                column_index = randi(1, suit);
                card_in_deck = app.deck_of_cards(row_index, column_index);
            end
            app.card1 = app.filename;
            app.deck_of_cards(row_index, column_index) = 0;
            app.user_count = 0;
            app.user_count = app.user_count + row_index;
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            [value, suit] = size(app.deck_of_cards); 
            card_in_deck = 0;
            while card_in_deck == 0
                row_index = randi(1,value);
                column_index = randi(1, suit);
                card_in_deck = app.deck_of_cards(row_index, column_index);
            end
            app.deck_of_cards(row_index, column_index) = 0;
            app.user_count = app.user_count + row_index;
        end

        % Button pushed function: StayButton
        function StayButtonPushed(app, event)
            if app.user_count > 21
                app.error_message_window.Value = 'You went over 21; therefore, you lost the game :(';
                app.YourEarningsTextArea.Value = -(placed_bet);
            elseif app.user_count == 21
                app.error_message_window.Value = 'Congratulations, you got a Blackjack! ';
                app.YourEarningsTextArea.Value = 1.5 * placed_bet;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcometoOnlineBlackjackLabel = uilabel(app.UIFigure);
            app.WelcometoOnlineBlackjackLabel.Text = 'Welcome to Online Blackjack!';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StayButton = uibutton(app.UIFigure, 'push');
            app.StayButton.ButtonPushedFcn = createCallbackFcn(app, @StayButtonPushed, true);
            app.StayButton.Text = 'Stay';
            app.card1 = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.SplitButton = uibutton(app.UIFigure, 'push');
            app.SplitButton.Text = 'Split';
            app.Image_2 = uiimage(app.UIFigure);
            app.YourHandLabel = uilabel(app.UIFigure);
            app.YourHandLabel.Text = 'Your Hand';
            app.DealersHandLabel = uilabel(app.UIFigure);
            app.DealersHandLabel.Text = 'Dealer''s Hand';
            app.Image4 = uiimage(app.UIFigure);
            app.Image5 = uiimage(app.UIFigure);
            app.Image6 = uiimage(app.UIFigure);
            app.Image7 = uiimage(app.UIFigure);
            app.MinimumBettingAmountEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumBettingAmountEditFieldLabel.Text = 'Minimum Betting Amount';
            app.MinimumBettingAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumBettingAmountEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumBettingAmountEditFieldValueChanged2, true);
            app.MinimumBettingAmountEditField.Value = -6;
            app.BettingAmountEditFieldLabel = uilabel(app.UIFigure);
            app.BettingAmountEditFieldLabel.Text = 'Betting Amount';
            app.BettingAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.BettingAmountEditField.ValueChangedFcn = createCallbackFcn(app, @BettingAmountEditFieldValueChanged, true);
            app.BettingAmountEditField.Value = -6;
            app.error_message_window = uitextarea(app.UIFigure);
            app.error_message_window.Value = {'Good Luck!'};
            app.YourEarningsTextAreaLabel = uilabel(app.UIFigure);
            app.YourEarningsTextAreaLabel.Text = 'Your Earnings';
            app.YourEarningsTextArea = uitextarea(app.UIFigure);
            app.YourEarningsTextArea.Value = {'0'; '0'};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end